classdef viewZwuisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        CLEARButton              matlab.ui.control.Button
        fSlider                  matlab.ui.control.Slider
        fSliderLabel             matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        EditField                matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        REMOVEButton             matlab.ui.control.Button
        ADDButton                matlab.ui.control.Button
        ZSlider                  matlab.ui.control.Slider
        ZSliderLabel             matlab.ui.control.Label
        YSlider                  matlab.ui.control.Slider
        YSliderLabel             matlab.ui.control.Label
        LOADButton               matlab.ui.control.Button
        MScanFileEditField       matlab.ui.control.EditField
        MScanFileEditFieldLabel  matlab.ui.control.Label
        BScanFileEditField       matlab.ui.control.EditField
        BScanFileEditFieldLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loaded = false;  % false if no data loaded in, true else
        deltaf_all = [];     % Displacement responses
        f   = [];     % Stimulus frequencies
        Yline  = [];     % line plot for vertical axis
        point  = [];     % point marking (Y,Z)
        selectedPoints = [];
        deltas = [];
        SPlt = [];
        huemap = 10*log10(1 + hsv(64)/1.5);

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function loadFiles(app, event)
            load(app.BScanFileEditField.Value,'BScan');
            load(app.MScanFileEditField.Value,'avgA','freq','delta_f_thresh');
            image(app.UIAxes,BScan*255/max(max(BScan))); colormap gray;
            app.UIAxes.YLim = [1,size(BScan,1)];
            app.UIAxes.XLim = [1,size(BScan,2)];
            app.UIAxes.Colormap = gray();

            % plot motion map
            f_ind = 1;
            cplx_map = squeeze(delta_f_thresh(:,:,f_ind));
            phase_map = 0.5 + angle(cplx_map)/2/pi;
            ind_hue = round(64*phase_map);
            maxval = max(max(abs(cplx_map)));
            colored_map = ind2rgb(ind_hue,app.huemap).*abs(cplx_map)./maxval;
            app.UIAxes_2.Visible = "on";
            Id = image(app.UIAxes_2,colored_map);
            Id.HitTest = 'off';


            app.UIAxes_2.YLim = [1,size(avgA,1)];
            app.UIAxes_2.XLim = [1,size(avgA,2)];
            app.Label.Text = ['BScan ',app.BScanFileEditField.Value(end-3:end)];
            app.Label_2.Text = ['MScan ',app.MScanFileEditField.Value(end-3:end),' MAX: ',num2str(maxval)];

            app.loaded = true;
            app.f = freq;
            app.deltaf_all = delta_f_thresh;
            app.YSlider.Limits = [1,size(avgA,2)];
            app.ZSlider.Limits = [1,size(avgA,1)];
            app.fSlider.Limits = [1,length(freq)];
            app.YSlider.MinorTicks = [];
            app.ZSlider.MinorTicks = [];
            app.fSlider.MinorTicks = [];
            app.YSlider.MajorTicks = 0:5:size(avgA,2);
            app.ZSlider.MajorTicks = 0:75:size(avgA,1);
            app.fSlider.MajorTicks = 0:5:length(freq);

            app.UIAxes2.XLim = [freq(1),freq(end)];
            app.UIAxes3.XLim = [freq(1),freq(end)];

        end

        % Value changed function: YSlider
        function ySlide(app, event)
            yvalue = floor(app.YSlider.Value);
            zvalue = floor(app.ZSlider.Value);
            if app.loaded
                % we have something loaded
                delete(app.Yline);
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.Yline = plot(app.UIAxes_2,[yvalue,yvalue],[0,1000],'k--',LineWidth=3);
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                app.EditField.Value = yvalue;
                app.EditField_2.Value = zvalue;
                hold(app.UIAxes_2,'off');
            end
        end

        % Value changed function: ZSlider
        function zSlide(app, event)
            zvalue = floor(app.ZSlider.Value);
            yvalue = floor(app.YSlider.Value);
            if app.loaded
                % we have something loaded
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                hold(app.UIAxes_2,'off');
                app.EditField.Value = yvalue;
                app.EditField_2.Value = zvalue;
            end
        end

        % Value changing function: ZSlider
        function zSliding(app, event)
            zvalue = floor(event.Value);
            yvalue = floor(app.YSlider.Value);
            if app.loaded
                % we have something loaded
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                hold(app.UIAxes_2,'off');
                app.EditField.Value = yvalue;
                app.EditField_2.Value = zvalue;
            end
        end

        % Value changing function: YSlider
        function ySliding(app, event)
            yvalue = floor(event.Value);
            zvalue = floor(app.ZSlider.Value);
            if app.loaded
                delete(app.Yline);
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.Yline = plot(app.UIAxes_2,[yvalue,yvalue],[0,1000],'k--',LineWidth=3);
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                hold(app.UIAxes_2,'off');
                app.EditField.Value = yvalue;
                app.EditField_2.Value = zvalue;
            end
        end

        % Value changed function: EditField
        function yBox(app, event)
            yvalue = floor(app.EditField.Value);
            app.EditField.Value = yvalue;
            zvalue = floor(app.ZSlider.Value);
            if (app.loaded) && (yvalue <= app.YSlider.Limits(2)) && (yvalue >= app.YSlider.Limits(1))
                delete(app.Yline);
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.Yline = plot(app.UIAxes_2,[yvalue,yvalue],[0,1000],'k--',LineWidth=3);
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                hold(app.UIAxes_2,'off');
                app.YSlider.Value = yvalue;
            end
        end

        % Value changed function: EditField_2
        function zBox(app, event)
            zvalue = floor(app.EditField_2.Value);
            app.EditField_2.Value = zvalue;
            yvalue = floor(app.YSlider.Value);
            if app.loaded  && (zvalue <= app.ZSlider.Limits(2)) && (zvalue >= app.ZSlider.Limits(1))
                delete(app.Yline);
                delete(app.point);
                hold(app.UIAxes_2,'on');
                app.Yline = plot(app.UIAxes_2,[yvalue,yvalue],[0,1000],'k--',LineWidth=3);
                app.point = scatter(app.UIAxes_2,yvalue,zvalue,'k','filled','o',LineWidth=3);
                hold(app.UIAxes_2,'off');
                app.ZSlider.Value = zvalue;
            end
        end

        % Button pushed function: ADDButton
        function addPoint(app, event)
            yvalue = floor(app.YSlider.Value);
            zvalue = floor(app.ZSlider.Value);
            if (app.loaded) && (yvalue>0) && (zvalue >0)
                if isempty(app.selectedPoints) % first point
                    app.selectedPoints = [yvalue,zvalue];
                    app.UITable.Data = app.selectedPoints;
                    app.deltas = squeeze(app.deltaf_all(zvalue,yvalue,:)).';
                    plot(app.UIAxes2,app.f,abs(app.deltas))
                    legend(app.UIAxes2)
                    plot(app.UIAxes3,app.f,unwrap(angle(app.deltas),pi,2)/(2*pi));
                    delete(app.SPlt);
                    hold(app.UIAxes_2,"on")
                    app.SPlt = scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                        'r','*');
                    
                    hold(app.UIAxes_2,"off")
                else % check if this point is new
                    if ~any(all(app.selectedPoints == [yvalue,zvalue],2))
                        app.selectedPoints = [app.selectedPoints;yvalue,zvalue];
                        app.UITable.Data = app.selectedPoints;

                        app.deltas = [app.deltas; ...
                            squeeze(app.deltaf_all(zvalue,yvalue,:)).'];

                        plot(app.UIAxes2,app.f,abs(app.deltas))
                        legend(app.UIAxes2)
                        plot(app.UIAxes3,app.f,unwrap(angle(app.deltas),pi,2)/(2*pi));
                        delete(app.SPlt);
                        hold(app.UIAxes_2,"on")
                        app.SPlt =scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                        'r','*');
                        hold(app.UIAxes_2,"off")
                    end
                end
            end
        end

        % Button pushed function: REMOVEButton
        function removeButton(app, event)
            yvalue = floor(app.YSlider.Value);
            zvalue = floor(app.ZSlider.Value);
            if (app.loaded) && (yvalue>0) && (zvalue >0)
                if isempty(app.selectedPoints) % first point
                    % do nothing
                else % check if this point is new

                    app.deltas = app.deltas(~all(app.selectedPoints==[yvalue,zvalue],2),:);
                    app.selectedPoints = app.selectedPoints(~all(app.selectedPoints==[yvalue,zvalue],2),:);
                    app.UITable.Data = app.selectedPoints;
                    
                    if ~isempty(app.deltas)
                        plot(app.UIAxes2,app.f,abs(app.deltas))
                        legend(app.UIAxes2)
                        plot(app.UIAxes3,app.f,unwrap(angle(app.deltas),[],2)/2/pi);
                    end
                
                end
            end
        end

        % Value changed function: fSlider
        function fSlide(app, event)
            fvalue = floor(app.fSlider.Value);
            app.EditField_3.Value = fvalue;
            if app.loaded

                cplx_map = squeeze(app.deltaf_all(:,:,fvalue));
                phase_map = 0.5 + angle(cplx_map)/2/pi;
                ind_hue = round(64*phase_map);
                colored_map = ind2rgb(ind_hue,app.huemap).*abs(cplx_map)./max(max(abs(cplx_map)));
                app.UIAxes_2.Visible = "on";
                Id = image(app.UIAxes_2,colored_map);
                Id.HitTest = 'off';

                if ~isempty(app.selectedPoints)
                    delete(app.SPlt);
                    hold(app.UIAxes_2,"on")
                    app.SPlt =scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                    'r','*');
                    hold(app.UIAxes_2,"off")
                end
            end
        end

        % Value changing function: fSlider
        function fSliding(app, event)
            fvalue = floor(event.Value);
            app.EditField_2.Value = fvalue;
            if app.loaded
                
                cplx_map = squeeze(app.deltaf_all(:,:,fvalue));
                phase_map = 0.5 + angle(cplx_map)/2/pi;
                ind_hue = round(64*phase_map);
                colored_map = ind2rgb(ind_hue,app.huemap).*abs(cplx_map)./max(max(abs(cplx_map)));
                app.UIAxes_2.Visible = "on";
                Id = image(app.UIAxes_2,colored_map);
                Id.HitTest = 'off';

                if ~isempty(app.selectedPoints)
                    delete(app.SPlt);
                    hold(app.UIAxes_2,"on")
                    app.SPlt =scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                    'r','*');
                    hold(app.UIAxes_2,"off")
                end
            end
        end

        % Value changed function: EditField_3
        function fBox(app, event)
            fvalue = floor(app.EditField_3.Value);
            if app.loaded  && (fvalue <= app.fSlider.Limits(2)) && (fvalue >= app.fSlider.Limits(1))
                app.fSlider.Value = fvalue;
                Id = imagesc(app.UIAxes_2,squeeze(abs(app.deltaf_all(:,:,fvalue))));
                Id.HitTest = 'off';
                delete(app.SPlt);
                hold(app.UIAxes_2,"on")
                app.SPlt =scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                'r','*');
                hold(app.UIAxes_2,"off")
            end
        end

        % Button pushed function: CLEARButton
        function clearButton(app, event)
            app.deltas = [];
            app.selectedPoints = [];
            app.UITable.Data = [];
        end

        % Button down function: UIAxes_2
        function pressImage(app, event)
            yvalue = round(event.IntersectionPoint(1));
            zvalue = round(event.IntersectionPoint(2));
            if (app.loaded) && (yvalue>0) && (zvalue >0)
                if isempty(app.selectedPoints) % first point
                    app.selectedPoints = [yvalue,zvalue];
                    app.UITable.Data = app.selectedPoints;
                    app.deltas = squeeze(app.deltaf_all(zvalue,yvalue,:)).';
                    plot(app.UIAxes2,app.f,abs(app.deltas))
                    legend(app.UIAxes2)
                    plot(app.UIAxes3,app.f,unwrap(angle(app.deltas),pi,2)/(2*pi));
                    delete(app.SPlt);
                    hold(app.UIAxes_2,"on")
                    app.SPlt = scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                        'r','*');

                    hold(app.UIAxes_2,"off")
                else % check if this point is new
                    if ~any(all(app.selectedPoints == [yvalue,zvalue],2))
                        app.selectedPoints = [app.selectedPoints;yvalue,zvalue];
                        app.UITable.Data = app.selectedPoints;

                        app.deltas = [app.deltas; ...
                            squeeze(app.deltaf_all(zvalue,yvalue,:)).'];

                        plot(app.UIAxes2,app.f,abs(app.deltas))
                        legend(app.UIAxes2)
                        plot(app.UIAxes3,app.f,unwrap(angle(app.deltas),pi,2)/(2*pi));
                        delete(app.SPlt);
                        hold(app.UIAxes_2,"on")
                        app.SPlt =scatter(app.UIAxes_2,app.selectedPoints(:,1),app.selectedPoints(:,2),...
                        'r','*');
                        hold(app.UIAxes_2,"off")
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Tag = 'BImage';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Displacement Magnitude (nm)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YScale = 'log';
            app.UIAxes2.Tag = 'ampPlot';
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Displacement Phase (cycles)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Tag = 'phasePlot';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @pressImage, true);
            app.UIAxes_2.Tag = 'avgAImage';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'PointList';
            app.BScanFileEditFieldLabel = uilabel(app.UIFigure);
            app.BScanFileEditFieldLabel.Text = 'BScan File';
            app.BScanFileEditField = uieditfield(app.UIFigure, 'text');
            app.BScanFileEditField.Tag = 'BFileName';
            app.BScanFileEditField.Value = 'Results/BScans/BScan_4320';
            app.MScanFileEditFieldLabel = uilabel(app.UIFigure);
            app.MScanFileEditFieldLabel.Text = 'MScan File';
            app.MScanFileEditField = uieditfield(app.UIFigure, 'text');
            app.MScanFileEditField.Tag = 'MFileName';
            app.MScanFileEditField.Value = 'Results/MScans/MScan_4321';
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @loadFiles, true);
            app.LOADButton.Tag = 'LoadButton';
            app.LOADButton.Text = 'LOAD';
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 20];
            app.YSlider.MajorTicks = [0 4 8 12 16 20];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @ySlide, true);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @ySliding, true);
            app.YSlider.MinorTicks = [];
            app.YSlider.Tag = 'YSlider';
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [0 400];
            app.ZSlider.MajorTicks = [0 80 160 240 320 400];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @zSlide, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @zSliding, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Tag = 'ZSlider';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @addPoint, true);
            app.ADDButton.Tag = 'AddButton';
            app.ADDButton.Text = 'ADD';
            app.REMOVEButton = uibutton(app.UIFigure, 'push');
            app.REMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @removeButton, true);
            app.REMOVEButton.Tag = 'RemoveButton';
            app.REMOVEButton.Text = 'REMOVE';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'BScanText';
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Tag = 'AvgMText';
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @yBox, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @zBox, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @fBox, true);
            app.fSliderLabel = uilabel(app.UIFigure);
            app.fSliderLabel.Text = 'f';
            app.fSlider = uislider(app.UIFigure);
            app.fSlider.Limits = [0 20];
            app.fSlider.MajorTicks = [0 4 8 12 16 20];
            app.fSlider.ValueChangedFcn = createCallbackFcn(app, @fSlide, true);
            app.fSlider.ValueChangingFcn = createCallbackFcn(app, @fSliding, true);
            app.fSlider.MinorTicks = [];
            app.fSlider.Tag = 'YSlider';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton, true);
            app.CLEARButton.Tag = 'RemoveButton';
            app.CLEARButton.Text = 'CLEAR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'colorwheel.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewZwuisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end